classdef trial1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GenerateButton          matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        RadioButton             matlab.ui.control.RadioButton
        RadioButton2            matlab.ui.control.RadioButton
        LabelDropDown           matlab.ui.control.Label
        SignalList              matlab.ui.control.DropDown
        LabelNumericEditField   matlab.ui.control.Label
        AmplitudeField          matlab.ui.control.NumericEditField
        LabelNumericEditField2  matlab.ui.control.Label
        FrequencyField          matlab.ui.control.NumericEditField
        Panel                   matlab.ui.container.Panel
        LabelSpinner            matlab.ui.control.Label
        Spinner                 matlab.ui.control.Spinner
        LabelSpinner2           matlab.ui.control.Label
        Spinner2                matlab.ui.control.Spinner
        LabelSpinner3           matlab.ui.control.Label
        Spinner3                matlab.ui.control.Spinner
        Knob                    matlab.ui.control.Knob
        LabelKnob               matlab.ui.control.Label
    end


    methods (Access = private)

        function y = process(app)
            ss = app.SignalList.Value;
            A = app.AmplitudeField.Value;
            f = app.FrequencyField.Value;
            t1 = app.Spinner.Value;
            interval = app.Spinner2.Value;
            t2 = app.Spinner3.Value;
            phase = app.Knob.Value;
            t = t1:interval:t2;
            if ss == 'Sine        '
                y = A.*sin(2*pi*f*t + phase);
            elseif ss == 'Cosine      ' 
                y = A.*cos(2*pi*f*t + phase);
            elseif ss == 'Ramp        '
                y = A.*t;
            elseif ss == 'Exponential '
                y = A.*exp(t);
            elseif ss == 'Parabolic   '
                y = A.*(t.^2)/2;
            elseif ss == 'Noise       '
                y = rand();
            elseif ss == 'Step        '
                y = A.*ones(1,t2-t1);
            elseif ss == 'Impulse     '
            elseif ss == 'Gate        '
            else
                y = 0;
            end
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: SignalList
        function SignalListValueChanged(app, event)
            value = app.SignalList.Value;
            
        end

        % Value changed function: AmplitudeField
        function AmplitudeFieldValueChanged(app, event)
            value = app.AmplitudeField.Value;
            
        end

        % Value changed function: FrequencyField
        function FrequencyFieldValueChanged(app, event)
            value = app.FrequencyField.Value;
            
        end

        % Selection changed function: ButtonGroup
        function TypeofSignal(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
        end

        % Button pushed function: GenerateButton
        function GenerateButtonButtonPushed(app, event)
            t1 = app.Spinner.Value;
            interval = app.Spinner2.Value;
            t2 = app.Spinner3.Value; 
            t = t1:interval:t2;
            y = process(app);
            %n = TypeofSignal();
            n = app.ButtonGroup.SelectedObject;
            if n == app.RadioButton
                plot(t,y);
            else
                stem(y);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofSignal, true);
            app.ButtonGroup.Title = 'Types';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'Continuous';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = 'Discrete';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Signal';
            app.SignalList = uidropdown(app.UIFigure);
            app.SignalList.Items = {'Sine        ', 'Cosine      ', 'Ramp        ', 'Exponential', 'Step        ', 'Impulse     ', 'Noise       ', 'Parabolic   ', 'Gate        '};
            app.SignalList.ValueChangedFcn = createCallbackFcn(app, @SignalListValueChanged, true);
            app.SignalList.Value = 'Sine        ';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Amplitude';
            app.AmplitudeField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeField.Limits = [0 Inf];
            app.AmplitudeField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeFieldValueChanged, true);
            app.AmplitudeField.Value = 1;
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Frequency';
            app.FrequencyField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyField.Limits = [0 Inf];
            app.FrequencyField.ValueChangedFcn = createCallbackFcn(app, @FrequencyFieldValueChanged, true);
            app.FrequencyField.Value = 1;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Time';
            app.LabelSpinner = uilabel(app.Panel);
            app.LabelSpinner.Text = 'Starting';
            app.Spinner = uispinner(app.Panel);
            app.LabelSpinner2 = uilabel(app.Panel);
            app.LabelSpinner2.Text = 'Step';
            app.Spinner2 = uispinner(app.Panel);
            app.LabelSpinner3 = uilabel(app.Panel);
            app.LabelSpinner3.Text = 'Ending';
            app.Spinner3 = uispinner(app.Panel);
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 180];
            app.Knob.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.Knob.MinorTicks = [];
            app.LabelKnob = uilabel(app.UIFigure);
            app.LabelKnob.Text = 'Phase';
        end
    end

    methods (Access = public)

        % Construct app
        function app = trial1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end